% description: Copyright 2018 Luca Della Santina
classdef Clarinet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ClarinetUIFigure   matlab.ui.Figure
        EpochTree          matlab.ui.container.Tree
        Lamp               matlab.ui.control.Lamp
        Log                matlab.ui.control.EditField
        UIAxes             matlab.ui.control.UIAxes
        Panel              matlab.ui.container.Panel
        btnFind            matlab.ui.control.Button
        btnOpenData        matlab.ui.control.Button
        btnAbout           matlab.ui.control.Button
        btnExport          matlab.ui.control.Button
        btnSave            matlab.ui.control.Button
        btnFilterSettings  matlab.ui.control.Button
        btnFilterRun       matlab.ui.control.Button
        cmbFilters         matlab.ui.control.DropDown
        EpochfiltersLabel  matlab.ui.control.Label
        tblMeta            matlab.ui.control.Table
        btnResponseType    matlab.ui.control.Switch
        cmbDevices         matlab.ui.control.DropDown
        XScaleType         matlab.ui.control.Switch
    end

    
    properties (Access = private)
        CellData        % Description
    end
    
    methods (Access = private)
        
        function populateEpochTree(app, fName)
            app.EpochTree.clo; % Clear previous tree
            app.tblMeta.Data = [];
            
            exp = uitreenode(app.EpochTree,'Text',fName,'NodeData',[]);            
            for i = 1:numel(app.CellData.epochs)
                epoch = app.CellData.epochs(i);
                uitreenode(exp,'Text',['Epoch ' num2str(epoch.get('epochNum'))],'NodeData',epoch);
            end 
            
            app.EpochTree.expand('all');
        end
        
        function plotEpoch(app, epochs)
            cla(app.UIAxes);
            if numel(epochs)>1
                hold(app.UIAxes, 'on');
            end
            
            Ymax = 0;
            Ymin = 0;
            Xmax = 1;
            for i = 1:numel(epochs)
                switch app.btnResponseType.Value
                    case 'Raw'
                        response = epochs(i).getResponse(app.cmbDevices.Value);
                    case 'Filtered'
                        if epochs(i).hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                            response = epochs(i).getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                        else
                            disp('No filtered Response available, using raw');
                            response = epochs(i).getResponse(app.cmbDevices.Value);
                        end
                end
                
                assignin('base','response', response);
                plot(app.UIAxes, 1:numel(response.quantity), response.quantity');
                
                % Define the range to display in the X-axis
                Xmax = max([Xmax, numel(response)]);
                xlim(app.UIAxes, [1 numel(response.quantity)]);
                
                % Define the range to display in the Y-axis
                Ymax = max([Ymax, max(response.quantity)]);
                Ymin = min([Ymin, min(response.quantity)]);
                if Ymin == Ymax % Special case of no signal or constant value
                    Ymin = Ymin -1;
                    Ymax = Ymax +1;
                elseif Ymin==0 && Ymax==1 % Special case of binary value, zoom out a bit
                    Ymin = -0.8;
                    Ymax = 1.2;
                end
                ylim(app.UIAxes, [Ymin Ymax]);

            end
            
            switch app.XScaleType.Value
                case 'Time'
                    meta = epochs(1).toStructure;
                    xt = get(app.UIAxes, 'XTick');                                 % 'XTick' Values
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt/meta.sampleRate); % Relabel 'XTick' With 'XTickLabel' Values
                case 'Samples'
                    xt = get(app.UIAxes, 'XTick');                                 % 'XTick' Values
                    set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt);                % Relabel 'XTick' With 'XTickLabel' Values
            end            
            %legend(app.UIAxes, 'lcn','best'); %TODO: pass epoch names as argument to this function to use as legend
        end
        
        function populateFilterList(app)
            filters = meta.package.fromName('filters').FunctionList;
            filterNames = {filters.Name};
            app.cmbFilters.Items = filterNames;
            app.cmbFiltersValueChanged;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.populateFilterList;
        end

        % Button pushed function: btnOpenData
        function btnOpenDataButtonPushed(app, event)
            [fName, PathName] = uigetfile('*.h5', 'Select experiments', 'MultiSelect', 'on');
            app.Lamp.Color = 'Yellow';
            app.Log.Value = ['Loading ' fName '...'];
            pause(0.2);

            app.ClarinetUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.ClarinetUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus

            if iscell(fName)
                cd(PathName);
                nbfiles = length(fName);
            elseif fName ~= 0
                cd(PathName);                
                nbfiles = 1;
            else
                nbfiles = 0;
            end

            import parsers.*
            if nbfiles == 1                
                dlg = uiprogressdlg(app.ClarinetUIFigure,'Title',['Loading ' fName],'Indeterminate','on');

                version = SymphonyParser.getVersion(fName);
                if version == 2
                    ref = SymphonyV2Parser(fName);
                else
                    ref = SymphonyV1Parser(fName);
                end
                ref.parse;
                data = ref.getResult;
                app.CellData = data{end};
                
                app.populateEpochTree(fName);
                cla(app.UIAxes);
                
                app.btnSave.Enable      = 'on';
                app.btnExport.Enable    = 'off';
                app.btnFind.Enable      = 'on';
                app.btnFilterRun.Enable = 'off';
                close(dlg);
            else
                app.btnSave.Enable      = 'off';
                app.btnExport.Enable    = 'off';
                app.btnFind.Enable      = 'off';
                app.btnFilterRun.Enable = 'off';
            end
            
            app.Log.Value = ['Loading ' fName '...DONE'];            
            app.Lamp.Color = 'Green';
        end

        % Selection changed function: EpochTree
        function EpochTreeSelectionChanged(app, event)
            selectedNodes = app.EpochTree.SelectedNodes;
            
            if numel(selectedNodes) == 1
                epoch = selectedNodes.NodeData;     % Retrieve epoch data
                if isempty(epoch)
                    app.tblMeta.Data = [];
                    app.cmbDevices.Items = {};
                    return
                end                
                app.cmbDevices.Items = epoch.get('devices'); % Populate available devices
                app.cmbFilters.UserData.device = app.cmbDevices.Value;
                app.tblMeta.Data = epoch.toTable;            % Populate metadata table
                app.plotEpoch(epoch);                        % Plot epoch data on screen
                
                app.btnExport.Enable    = 'on';
                app.btnFilterRun.Enable = 'on';
            elseif numel(selectedNodes) > 1
                app.tblMeta.Data= [];
                for i= numel(selectedNodes):-1:1
                    epochs(i) = selectedNodes(i).NodeData;     % Retrieve epoch data
                end
                app.cmbDevices.Items = epochs(1).get('devices'); % Populate available devices
                app.cmbFilters.UserData.device = app.cmbDevices.Value;                
                app.plotEpoch(epochs);               % Plot epoch data on screen

                app.btnExport.Enable    = 'on';
                app.btnFilterRun.Enable = 'on';
            else
                app.btnExport.Enable    = 'off';
                app.btnFilterRun.Enable = 'off';
            end
        end

        % Button pushed function: btnExport
        function btnExportButtonPushed(app, event)
            selectedNodes = app.EpochTree.SelectedNodes;
            
            if numel(selectedNodes) >= 1
                for i= numel(selectedNodes):-1:1
                    epoch = selectedNodes(i).NodeData;
                    meta = epoch.toStructure;

                    % Filter the raw response if no previous filter was applied or user selected 'Raw'
                    switch app.btnResponseType.Value
                        case 'Raw'
                            response = epoch.getResponse(app.cmbDevices.Value);
                        case 'Filtered'
                            if epoch.hasDerivedResponse('filteredResponse', app.cmbDevices.Value)
                                response = epoch.getDerivedResponse('filteredResponse', app.cmbDevices.Value);
                            else
                                response = epoch.getResponse(app.cmbDevices.Value); % Use raw response if no filtered is available
                            end
                    end

                    epochs(i).epoch = response.quantity'; % Retrieve epoch data
                    epochs(i).meta  = meta;               % Retrieve epoch metadata
                end
                uisave({'epochs'},'SelectedEpochs.mat');
            end            
        end

        % Button pushed function: btnAbout
        function btnAboutButtonPushed(app, event)
            dlg = DialogAbout;

            % Center the dialog on main window
            pos = dlg.UIFigure.Position;
            posClarinet = app.ClarinetUIFigure.Position;
            pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
            pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
            dlg.UIFigure.Position = pos;            
        end

        % Value changed function: cmbFilters
        function cmbFiltersValueChanged(app, event)
            import filters.*
            
            % Restrieve default settings
            filterName = app.cmbFilters.Value;
            settings = feval(filterName,[],[]);

            % Ensure default device is an available one
            selectedNodes = app.EpochTree.SelectedNodes;
            if numel(selectedNodes) > 0
                settings.device = app.cmbDevices.Value;
            end
            
            if numel(fieldnames(settings)) > 0
                app.btnFilterSettings.Enable = 'on';
            else
                app.btnFilterSettings.Enable = 'off';
            end
            
            app.cmbFilters.Tooltip = help(filterName);
            app.btnFilterSettings.UserData = settings;
        end

        % Button pushed function: btnFilterRun
        function btnFilterRunButtonPushed(app, event)
            import filters.*
            
            % Retrieve choosen filter name and settings
            filterName  = app.cmbFilters.Value;
            settings    = app.btnFilterSettings.UserData; 
                        
            % Execute the choosen filter on selected epochs            
            selectedNodes = app.EpochTree.SelectedNodes;
            for i=1:numel(selectedNodes)
                epoch   = selectedNodes(i).NodeData;

                % Filter the raw response if no previous filter was applied or user selected 'Raw'
                if strcmp(app.btnResponseType.Value,'Raw') || ~epoch.hasDerivedResponse('filteredResponse', settings.device)
                    response = epoch.getResponse(settings.device);
                    epoch.addDerivedResponse('filteredResponse', response, settings.device);
                end
                
                result  = feval(filterName, epoch, settings);
                selectedNodes(i).NodeData = result;
            end
            
            app.btnResponseType.Value = 'Filtered';
            app.EpochTreeSelectionChanged;
        end

        % Button pushed function: btnFilterSettings
        function btnFilterSettingsButtonPushed(app, event)
            settings = app.btnFilterSettings.UserData;
            
            % Ensure default device is an available one
            selectedNodes = app.EpochTree.SelectedNodes;
            if numel(selectedNodes) > 0
                settings.device = app.cmbDevices.Value;
            end

            newSettings = editStruct(settings);
            if ~isempty(newSettings)
                app.btnFilterSettings.UserData = newSettings;
            end
        end

        % Button pushed function: btnFind
        function btnFindButtonPushed(app, event)
            dlg = DialogFind(app);
            dlg.UIFigure.Visible = 'off';
            
            % Center the dialog on main window
            pos = dlg.UIFigure.Position;
            posClarinet = app.ClarinetUIFigure.Position;
            pos(1) = ceil(posClarinet(1)+posClarinet(3)/2-pos(3)/2);
            pos(2) = ceil(posClarinet(2)+posClarinet(4)/2-pos(4)/2);
            dlg.UIFigure.Position = pos;
            
            dlg.UIFigure.Visible = 'on';            
        end

        % Value changed function: btnResponseType
        function btnResponseTypeValueChanged(app, event)
            app.EpochTreeSelectionChanged;            
        end

        % Value changed function: cmbDevices
        function cmbDevicesValueChanged(app, event)
            app.EpochTreeSelectionChanged;            
        end

        % Value changed function: XScaleType
        function XScaleTypeValueChanged(app, event)
            app.EpochTreeSelectionChanged;
        end

        % Button pushed function: btnSave
        function btnSaveButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ClarinetUIFigure = uifigure;
            app.ClarinetUIFigure.Name = 'Clarinet';
            app.EpochTree = uitree(app.ClarinetUIFigure);
            app.EpochTree.Multiselect = 'on';
            app.EpochTree.SelectionChangedFcn = createCallbackFcn(app, @EpochTreeSelectionChanged, true);
            app.Lamp = uilamp(app.ClarinetUIFigure);
            app.Lamp.Tooltip = {'App status:'; 'Green = OK'; 'Yellow = Busy'; 'Red = Error'};
            app.Log = uieditfield(app.ClarinetUIFigure, 'text');
            app.Log.Tooltip = {'Status bar keeping track of last event occurred'};
            app.Log.Value = 'Welcome to Clarinet, open an experiment to begin';
            app.UIAxes = uiaxes(app.ClarinetUIFigure);
            app.Panel = uipanel(app.ClarinetUIFigure);
            app.btnFind = uibutton(app.Panel, 'push');
            app.btnFind.ButtonPushedFcn = createCallbackFcn(app, @btnFindButtonPushed, true);
            app.btnFind.Icon = '48px-search.png';
            app.btnFind.Enable = 'off';
            app.btnFind.Tooltip = {'Select epochs meeting specific conditions'};
            app.btnFind.Text = 'Search';
            app.btnOpenData = uibutton(app.Panel, 'push');
            app.btnOpenData.ButtonPushedFcn = createCallbackFcn(app, @btnOpenDataButtonPushed, true);
            app.btnOpenData.Icon = '48px-open.png';
            app.btnOpenData.Tooltip = {'Load epochs from an experiment'};
            app.btnOpenData.Text = 'Open';
            app.btnAbout = uibutton(app.Panel, 'push');
            app.btnAbout.ButtonPushedFcn = createCallbackFcn(app, @btnAboutButtonPushed, true);
            app.btnAbout.Icon = '48px-info.png';
            app.btnAbout.Tooltip = {'Information about Clarinet'};
            app.btnAbout.Text = 'About';
            app.btnExport = uibutton(app.Panel, 'push');
            app.btnExport.ButtonPushedFcn = createCallbackFcn(app, @btnExportButtonPushed, true);
            app.btnExport.Icon = '48px-export.png';
            app.btnExport.Enable = 'off';
            app.btnExport.Tooltip = {'Export selected epochs data to .mat file'};
            app.btnExport.Text = {'Export'; ''};
            app.btnSave = uibutton(app.Panel, 'push');
            app.btnSave.ButtonPushedFcn = createCallbackFcn(app, @btnSaveButtonPushed, true);
            app.btnSave.Icon = '48px-save.png';
            app.btnSave.Enable = 'off';
            app.btnSave.Tooltip = {'Save the experiment into .mat file'};
            app.btnSave.Text = 'Save';
            app.btnFilterSettings = uibutton(app.Panel, 'push');
            app.btnFilterSettings.ButtonPushedFcn = createCallbackFcn(app, @btnFilterSettingsButtonPushed, true);
            app.btnFilterSettings.Icon = '48px-settings.png';
            app.btnFilterSettings.Tooltip = {'Edit settings for chosen filter'};
            app.btnFilterSettings.Text = 'Settings';
            app.btnFilterRun = uibutton(app.Panel, 'push');
            app.btnFilterRun.ButtonPushedFcn = createCallbackFcn(app, @btnFilterRunButtonPushed, true);
            app.btnFilterRun.Icon = '48px-execute.png';
            app.btnFilterRun.Enable = 'off';
            app.btnFilterRun.Tooltip = {'Filter selected epochs'};
            app.btnFilterRun.Text = 'Apply Filter';
            app.cmbFilters = uidropdown(app.Panel);
            app.cmbFilters.Items = {};
            app.cmbFilters.ValueChangedFcn = createCallbackFcn(app, @cmbFiltersValueChanged, true);
            app.cmbFilters.Value = {};
            app.EpochfiltersLabel = uilabel(app.Panel);
            app.EpochfiltersLabel.Text = 'Epoch filters';
            app.tblMeta = uitable(app.ClarinetUIFigure);
            app.tblMeta.ColumnName = {'Property'; 'Value'};
            app.tblMeta.RowName = {};
            app.btnResponseType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.btnResponseType.Items = {'Raw', 'Filtered'};
            app.btnResponseType.ValueChangedFcn = createCallbackFcn(app, @btnResponseTypeValueChanged, true);
            app.btnResponseType.Tooltip = {'Epoch version to process'};
            app.btnResponseType.Value = 'Raw';
            app.cmbDevices = uidropdown(app.ClarinetUIFigure);
            app.cmbDevices.Items = {};
            app.cmbDevices.ValueChangedFcn = createCallbackFcn(app, @cmbDevicesValueChanged, true);
            app.cmbDevices.Tooltip = {'Instrument generating epochs'};
            app.cmbDevices.Value = {};
            app.XScaleType = uiswitch(app.ClarinetUIFigure, 'slider');
            app.XScaleType.Items = {'Time', 'Samples'};
            app.XScaleType.ValueChangedFcn = createCallbackFcn(app, @XScaleTypeValueChanged, true);
            app.XScaleType.Tooltip = {'Switch the abscissa scale'};
            app.XScaleType.Value = 'Time';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Clarinet

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ClarinetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ClarinetUIFigure)
        end
    end
end